classdef vhtp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ParadigmsListBoxLabel          matlab.ui.control.Label
        ParadigmsListBox               matlab.ui.control.ListBox
        IndividualStepsListBoxLabel    matlab.ui.control.Label
        IndividualStepsListBox         matlab.ui.control.ListBox
        StepsAbletoUseinRerunDropDownLabel  matlab.ui.control.Label
        StepsAbletoUseinRerunDropDown  matlab.ui.control.DropDown
        ProcessingStyleButtonGroup     matlab.ui.container.ButtonGroup
        CompleteProcessButton          matlab.ui.control.ToggleButton
        IndividualProcessStepButton    matlab.ui.control.ToggleButton
        RerunProcessStepButton         matlab.ui.control.ToggleButton
        RUNButton                      matlab.ui.control.StateButton
        TestRunCheckBox                matlab.ui.control.CheckBox
        SelectFilesButton              matlab.ui.control.Button
        BaseFileorDirectoryEditFieldLabel  matlab.ui.control.Label
        BaseFileorDirectoryEditField   matlab.ui.control.EditField
        OutputDirectoryLabel           matlab.ui.control.Label
        OutputDirectoryEditField       matlab.ui.control.EditField
        SelectOutputFolderButton       matlab.ui.control.Button
        SingleFileModeCheckBox         matlab.ui.control.CheckBox
        WorkingfromOriginalFilesCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        PARAMS=struct(); % Description
        DRYRUN=0;
        PROCESS='';
        SOURCEFILES=0;
    end

    methods (Access = private)
    
        function allowProcessStyle(app)
            app.ProcessingStyleButtonGroup.Visible = 'on';
            app.CompleteProcessButton.Visible = 'on';
            app.IndividualProcessStepButton.Visible = 'on';
            app.RerunProcessStepButton.Visible = 'on';
            app.CompleteProcessButton.Enable=1;
            app.IndividualProcessStepButton.Enable=1;
            app.RerunProcessStepButton.Enable=1;
            app.CompleteProcessButton.Value=0;
            app.IndividualProcessStepButton.Value=0;
            app.RerunProcessStepButton.Value=0;
        end
        
        function denyProcessStyle(app)
            app.ProcessingStyleButtonGroup.Visible = 'off';
            app.CompleteProcessButton.Visible = 'off';
            app.IndividualProcessStepButton.Visible = 'off';
            app.RerunProcessStepButton.Visible = 'off';
            app.CompleteProcessButton.Enable=0;
            app.IndividualProcessStepButton.Enable=0;
            app.RerunProcessStepButton.Enable=0;
            app.CompleteProcessButton.Value=0;
            app.IndividualProcessStepButton.Value=0;
            app.RerunProcessStepButton.Value=0;
        end
        
        function allowParadigms(app)
            paradigms = regexp({ls(fullfile(fileparts(mfilename('fullpath')),'paradigms','*.m'))},'(?<=paradigm_)\w*','match');
            app.ParadigmsListBox.Items = string(paradigms{:});
            app.ParadigmsListBox.ItemsData = string(paradigms{:});
            app.ParadigmsListBox.Visible = 'on';
            app.ParadigmsListBox.Enable = 'on';
            
        end
    
        function denyParadigms(app)
            %app.ParadigmsListBox.Value = {};
            app.ParadigmsListBox.Items = {};
            app.ParadigmsListBox.ItemsData = {};
            app.ParadigmsListBox.Visible = 'off';
            app.ParadigmsListBox.Enable = 'off'; 
            
        end
    
        function allowIndividualSteps(app)
            individualsteps = fieldnames(app.PARAMS);
            app.IndividualStepsListBox.Items = individualsteps;
            app.IndividualStepsListBox.ItemsData = individualsteps;
            app.IndividualStepsListBox.Visible = 'on';
            app.IndividualStepsListBox.Enable = 'on';
            
        end
    
        function denyIndividualSteps(app)
            app.IndividualStepsListBox.Items = {};
            app.IndividualStepsListBox.ItemsData = {};
            app.IndividualStepsListBox.Visible = 'off';
            app.IndividualStepsListBox.Enable = 'off';
        end
    
        function allowRerunSteps(app)
            steps=fieldnames(app.PARAMS);
            if app.SingleFileModeCheckBox.Value
                [folder,~,~] = fileparts(app.BaseFileorDirectoryEditField.Value);
                prev_file_dir = fullfile(folder,'prev_files');           
            else
                prev_file_dir = fullfile(app.BaseFileorDirectoryEditField.Value,'prev_files');
            end
            files = dir(fullfile(prev_file_dir,'**\*.set'));
            sourcefiles = sum(contains(string(char(files(:).folder)),'prev_files\source'));
            files=files(~contains(string(char(files(:).folder)),'prev_files\source'));
            temp=arrayfun(@(x) regexp(x,fieldnames(app.PARAMS),'match','once'),string(char(files(:).folder)),'UniformOutput',false);
            final=cellfun(@(x) string(steps(~ismissing(x))),temp);
            files=string(unique(final));
            if sourcefiles==0
                rerunsteps = {files};
            else
                rerunsteps={["source"; files(:)]};
            end
                
            
            app.StepsAbletoUseinRerunDropDown.Items = rerunsteps{:};
            app.StepsAbletoUseinRerunDropDown.ItemsData = rerunsteps{:};
            app.StepsAbletoUseinRerunDropDown.Visible = 'on';
            app.StepsAbletoUseinRerunDropDown.Enable = 'on';
        end
        
        function denyRerunSteps(app)
            app.StepsAbletoUseinRerunDropDown.Items = {};
            app.StepsAbletoUseinRerunDropDown.ItemsData = {};
            app.StepsAbletoUseinRerunDropDown.Visible = 'off';
            app.StepsAbletoUseinRerunDropDown.Enable = 'off';
        end
        
        function denyAll(app)
            denyParadigms(app);
            denyProcessStyle(app);
            denyIndividualSteps(app);
            denyRerunSteps(app); 
            app.RUNButton.Value = 0;
            
        end
        
        function initializePreviousFileDirectories(app,prev_file_dir)
            stepnames = fieldnames(app.PARAMS);
            if app.SOURCEFILES && ~exist(fullfile(prev_file_dir,'source'),'dir')
                mkdir(fullfile(prev_file_dir,'source'));
            end
            for i=1:length(stepnames)
                if ~exist(fullfile(prev_file_dir,stepnames{i}),'dir')
                    mkdir(fullfile(prev_file_dir,stepnames{i}));
                end
            end
        end
        
        function moveFiles(app,prev_file_dir,files_to_move)
            if any(strcmp(app.PROCESS,{'Rerun','IndividualStep'}))
                if app.SingleFileModeCheckBox.Value
                    cellfun(@(x) movefile(x,prev_file_dir),files_to_move);
                else
                    arrayfun(@(x) movefile(fullfile(x.folder,x.name),prev_file_dir),files_to_move);
                end
            else
                
                folders = arrayfun(@(x) regexp(x,fieldnames(app.PARAMS),'match','once'),string(char(files_to_move(:).name)),'UniformOutput',false);
                if length(fieldnames(app.PARAMS))>1
                    temp=cellfun(@(x) ismissing(x),folders,'UniformOutput',false);
                    folders(cellfun(@(x) all(x),temp))={"source"};
                    folders=cellfun(@(x) rmmissing(x),folders);
                else
                    folders(ismissing(string(folders))) = {"source"};
                end
                for i=1:length(files_to_move)
                    movefile(fullfile(files_to_move(i).folder,files_to_move(i).name),fullfile(prev_file_dir,folders{i}));
                end
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                eeglab nogui;
            catch
                app.delete()
                error(sprintf("PLEASE MAKE SURE YOUR EEGLAB DIRECTORY IS ON THE PATH.\n"))
                
            end
            addpath(genpath('../vhtp'));
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            startFolder = pwd;
            if app.SingleFileModeCheckBox.Value
                [file,folder] = uigetfile('*.set','Select a File for Input');
                if file==0
                    figure(app.UIFigure);
                    return;
                end
                fullFileName = fullfile(folder,file);
                app.BaseFileorDirectoryEditField.Value = fullFileName;
            else
                [folder] = uigetdir('Select a Folder for Input');
                if folder==0
                    figure(app.UIFigure);
                    return;
                end
                 app.BaseFileorDirectoryEditField.Value = folder;
                 
            end
            figure(app.UIFigure);
            if ~isempty(app.OutputDirectoryEditField.Value)
                allowParadigms(app);
            end
        end

        % Value changed function: SingleFileModeCheckBox
        function SingleFileButtonPushed(app, event)
            value = app.SingleFileModeCheckBox.Value;
            if value
                if ~isempty(app.BaseFileorDirectoryEditField.Value)
                    app.BaseFileorDirectoryEditField.Value = '';
                    denyAll(app);
                    
                end
                app.SelectFilesButton.Text = 'Select Files';
            else
                if ~isempty(app.BaseFileorDirectoryEditField.Value)
                    app.BaseFileorDirectoryEditField.Value = '';
                    denyAll(app);
                end
                app.SelectFilesButton.Text = 'Select Folder';
            end
        end

        % Button pushed function: SelectOutputFolderButton
        function OutputFolderButtonPushed(app, event)
            [folder] = uigetdir('Select a Folder for Output');
            if folder==0
                figure(app.UIFigure);
                return;
            end
            app.OutputDirectoryEditField.Value = folder;
            figure(app.UIFigure);
            if ~isempty(app.BaseFileorDirectoryEditField.Value)
                allowParadigms(app);
            end
                 
        end

        % Value changed function: ParadigmsListBox
        function ParadigmListBoxChanged(app, event)
            value = app.ParadigmsListBox.Value;
            if ~isempty(value)
                %run(fullfile(pwd,'parameters',strcat('parameters_',value)));
                func = str2func(strcat("parameters_",value));
                app.PARAMS=func();
                allowProcessStyle(app);
                app.PROCESS='All';
            else
                denyAll(app);
            end
        end

        % Value changed function: BaseFileorDirectoryEditField
        function BaseFileorDirectoryEditFieldChanged(app, event)
            value = app.BaseFileorDirectoryEditField.Value;
            if ~isempty(value)
                allowParadigms(app);
            else
                denyAll(app);
            end
        end

        % Value changed function: OutputDirectoryEditField
        function OutputDirectoryEditFieldChanged(app, event)
            value = app.OutputDirectoryEditField.Value;
            if ~isempty(value)
                allowParadigms(app);
            else
                denyAll(app);
            end
        end

        % Selection changed function: ProcessingStyleButtonGroup
        function ProcessingStyleButtonGroupChanged(app, event)
            selectedButton = app.ProcessingStyleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Individual Process Step')
                allowIndividualSteps(app);
                denyRerunSteps(app);
                app.PROCESS = 'IndividualStep';
            elseif strcmp(selectedButton.Text, 'Rerun Process Step')
               allowRerunSteps(app);
               denyIndividualSteps(app);
               app.PROCESS = 'Rerun';
            else
                
                denyIndividualSteps(app);
                denyRerunSteps(app);
                app.PROCESS = 'All';
            end
        end

        % Value changed function: TestRunCheckBox
        function TestRunCheckBoxChanged(app, event)
            value = app.TestRunCheckBox.Value;
            app.DRYRUN = value;
        end

        % Value changed function: RUNButton
        function RunButtonChanged(app, event)
            value = app.RUNButton.Value;
            if value
                if ~isempty(fieldnames(app.PARAMS))
                    func=str2func(strcat("paradigm_",app.ParadigmsListBox.Value));
                    if app.SingleFileModeCheckBox.Value
                        [folder,file,~] = fileparts(app.BaseFileorDirectoryEditField.Value);
                        prev_file_dir = fullfile(folder,'prev_files');
                        files_to_move={app.BaseFileorDirectoryEditField.Value; fullfile(folder,[file '.fdt']);};
                        
                    else
                        prev_file_dir = fullfile(app.BaseFileorDirectoryEditField.Value,'prev_files');
                        files_to_move = dir(fullfile(app.BaseFileorDirectoryEditField.Value));
                        files_to_move = files_to_move(~[files_to_move.isdir]);
                    end
                    if ~exist(prev_file_dir,'dir')
                        mkdir(prev_file_dir);
                    end
                    initializePreviousFileDirectories(app,prev_file_dir);
                    if strcmp(app.PROCESS,'Rerun')
                        if app.SOURCEFILES
                            prev_file_dir = fullfile(prev_file_dir,'source');
                        else
                           steps = fieldnames(app.PARAMS);
                           if app.SingleFileModeCheckBox.Value
                               step_in_use = regexp(files_to_move{1},fieldnames(app.PARAMS));
                           else
                               step_in_use = regexp(files_to_move(1).name,fieldnames(app.PARAMS));
                               main_step = steps(cellfun(@(x) ~isempty(x),step_in_use));
                           end
                           prev_file_dir = char(fullfile(prev_file_dir, main_step));
                           steps=[];
                           step_in_use=[];
                        end
                        func(app.BaseFileorDirectoryEditField.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',prev_file_dir,'dryrun',false,'rerunstep',char(main_step),'rerunsourcestep',char(app.StepsAbletoUseinRerunDropDown.Value));
                        %func(app.BaseFileorDirectoryEditField.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',app.OutputDirectoryEditField.Value,'dryrun',false,'rerunstep',char(rerunstep));
                         moveFiles(app,prev_file_dir,files_to_move);
                    elseif strcmp(app.PROCESS,'IndividualStep')      
                        if app.SOURCEFILES
                            prev_file_dir = fullfile(prev_file_dir,'source');
                        else
                           steps = fieldnames(app.PARAMS);
                           if app.SingleFileModeCheckBox.Value
                               step_in_use = regexp(files_to_move{1},fieldnames(app.PARAMS));
                           else
                               step_in_use = regexp(files_to_move(1).name,fieldnames(app.PARAMS));
                           end
                           prev_file_dir = char(fullfile(prev_file_dir, steps(cellfun(@(x) ~isempty(x),step_in_use))));
                           steps=[];
                           step_in_use=[];
                        end
                        func(app.BaseFileorDirectoryEditField.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',prev_file_dir,'dryrun',false,'individualstep',char(app.IndividualStepsListBox.Value));
                        moveFiles(app,prev_file_dir,files_to_move);
                    else
                        if ~app.SOURCEFILES
                            uialert(app.UIFigure, {'Please Mark that these are Original Files','Original File Checkbox Missing Check.'},'Error','Icon','error');
                            return;
                        end
                        func(app.BaseFileorDirectoryEditField.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',app.OutputDirectoryEditField.Value,'dryrun',false);
                        if app.SingleFileModeCheckBox.Value
                            [folder,file,~] = fileparts(app.BaseFileorDirectoryEditField.Value);
                            prev_file_dir = fullfile(folder,'prev_files');
                            %files_to_move={app.BaseFileorDirectoryEditField.Value; fullfile(folder,[file '.fdt']);};
                            files_to_move=dir(fullfile(folder,[file '*']));
                        else
                            files_to_move = dir(fullfile(app.BaseFileorDirectoryEditField.Value));
                            files_to_move = files_to_move(~[files_to_move.isdir]);
                        end
                        moveFiles(app,prev_file_dir,files_to_move);
                    end
                    
                end
            end
        end

        % Value changed function: WorkingfromOriginalFilesCheckBox
        function WorkingfromOriginalFilesCheckBoxChanged(app, event)
            value = app.WorkingfromOriginalFilesCheckBox.Value;
            app.SOURCEFILES = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ParadigmsListBoxLabel = uilabel(app.UIFigure);
            app.ParadigmsListBoxLabel.Text = 'Paradigms';
            app.ParadigmsListBox = uilistbox(app.UIFigure);
            app.ParadigmsListBox.Items = {'{}'};
            app.ParadigmsListBox.ItemsData = {'{}'};
            app.ParadigmsListBox.ValueChangedFcn = createCallbackFcn(app, @ParadigmListBoxChanged, true);
            app.ParadigmsListBox.Enable = 'off';
            app.ParadigmsListBox.Visible = 'off';
            app.ParadigmsListBox.Value = {};
            app.IndividualStepsListBoxLabel = uilabel(app.UIFigure);
            app.IndividualStepsListBoxLabel.Text = 'Individual Steps';
            app.IndividualStepsListBox = uilistbox(app.UIFigure);
            app.IndividualStepsListBox.Items = {'{}'};
            app.IndividualStepsListBox.ItemsData = {'{}'};
            app.IndividualStepsListBox.Enable = 'off';
            app.IndividualStepsListBox.Visible = 'off';
            app.IndividualStepsListBox.Value = '{}';
            app.StepsAbletoUseinRerunDropDownLabel = uilabel(app.UIFigure);
            app.StepsAbletoUseinRerunDropDownLabel.Text = 'Steps Able to Use in Rerun';
            app.StepsAbletoUseinRerunDropDown = uidropdown(app.UIFigure);
            app.StepsAbletoUseinRerunDropDown.Items = {'[ ]'};
            app.StepsAbletoUseinRerunDropDown.Enable = 'off';
            app.StepsAbletoUseinRerunDropDown.Visible = 'off';
            app.StepsAbletoUseinRerunDropDown.Value = '[ ]';
            app.ProcessingStyleButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessingStyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessingStyleButtonGroupChanged, true);
            app.ProcessingStyleButtonGroup.Title = 'Processing Style';
            app.CompleteProcessButton = uitogglebutton(app.ProcessingStyleButtonGroup);
            app.CompleteProcessButton.Enable = 'off';
            app.CompleteProcessButton.Visible = 'off';
            app.CompleteProcessButton.Text = 'Complete Process';
            app.IndividualProcessStepButton = uitogglebutton(app.ProcessingStyleButtonGroup);
            app.IndividualProcessStepButton.Enable = 'off';
            app.IndividualProcessStepButton.Visible = 'off';
            app.IndividualProcessStepButton.Text = 'Individual Process Step';
            app.IndividualProcessStepButton.Value = true;
            app.RerunProcessStepButton = uitogglebutton(app.ProcessingStyleButtonGroup);
            app.RerunProcessStepButton.Enable = 'off';
            app.RerunProcessStepButton.Visible = 'off';
            app.RerunProcessStepButton.Text = 'Rerun Process Step';
            app.RUNButton = uibutton(app.UIFigure, 'state');
            app.RUNButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonChanged, true);
            app.RUNButton.Text = 'RUN';
            app.TestRunCheckBox = uicheckbox(app.UIFigure);
            app.TestRunCheckBox.ValueChangedFcn = createCallbackFcn(app, @TestRunCheckBoxChanged, true);
            app.TestRunCheckBox.Text = 'Test Run?';
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.BaseFileorDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.BaseFileorDirectoryEditFieldLabel.Text = 'Base File or Directory:';
            app.BaseFileorDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.BaseFileorDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @BaseFileorDirectoryEditFieldChanged, true);
            app.OutputDirectoryLabel = uilabel(app.UIFigure);
            app.OutputDirectoryLabel.Text = 'Output Directory:';
            app.OutputDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.OutputDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @OutputDirectoryEditFieldChanged, true);
            app.SelectOutputFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.SelectOutputFolderButton.Text = 'Select Output Folder';
            app.SingleFileModeCheckBox = uicheckbox(app.UIFigure);
            app.SingleFileModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleFileButtonPushed, true);
            app.SingleFileModeCheckBox.Text = 'Single File Mode?';
            app.WorkingfromOriginalFilesCheckBox = uicheckbox(app.UIFigure);
            app.WorkingfromOriginalFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @WorkingfromOriginalFilesCheckBoxChanged, true);
            app.WorkingfromOriginalFilesCheckBox.Text = 'Working from Original File(s)?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = vhtp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end